classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BlackjackManMachinePanel  matlab.ui.container.Panel
        CurrentBetEditField       matlab.ui.control.NumericEditField
        CurrentBetEditFieldLabel  matlab.ui.control.Label
        EStopToggleButton         matlab.ui.control.StateButton
        BalanceEditField          matlab.ui.control.NumericEditField
        BalanceEditFieldLabel     matlab.ui.control.Label
        StartResetButton          matlab.ui.control.Button
        HitMeButton               matlab.ui.control.Button
        StandButton               matlab.ui.control.Button
    end

    properties (Access = private)
        Game_Logic % Description
        BlackjackGame   % Instance of BlackjackTest class
        playerHand      % Player's hand (array)
        dealerHand      % Dealer's hand (array)
        currentBet      % Player's current bet
    end

    methods (Access = private)

        function updateDisplay(app)
            % Update Player Hand Text Area
            app.PlayerHandTextArea.Value = ['Player Hand: ', app.BlackjackGame.handToString(app.playerHand), ...
                ' | Total: ', num2str(app.BlackjackGame.calculateTotal(app.playerHand))];

            % Update Dealer Hand Text Area (hiding second card unless game is over)
            if strcmp(app.HitButton.Enable, 'on')  % Game is still ongoing
                app.DealerHandTextArea.Value = ['Dealer Hand: ', app.BlackjackGame.cardNames{app.dealerHand(1)}, ' and [Hidden]'];
            else  % Game is over, show all dealer cards
                app.DealerHandTextArea.Value = ['Dealer Hand: ', app.BlackjackGame.handToString(app.dealerHand), ...
                    ' | Total: ', num2str(app.BlackjackGame.calculateTotal(app.dealerHand))];
            end

            % Update Balance
            app.BalanceLabel.Text = ['Balance: $', num2str(app.BlackjackGame.balance)];

        end

        % Function to handle the end of the game (disable buttons and show message)
        function endGame(app, message)
            % Disable Hit and Stand buttons
            app.HitButton.Enable = 'off';
            app.StandButton.Enable = 'off';

            % Display end game message
            uialert(app.UIFigure, message, 'Game Over');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartResetButton
        function StartResetButtonPushed(app, event)
            % Initialize game logic
            app.BlackjackGame = BlackjackTest();  % Create new instance of game logic
            app.currentBet = str2double(app.BetField.Value);  % Get the bet from the user input

            % Deal cards to player and dealer
            [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
            [app.dealerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);

            % Update the display
            app.updateDisplay();

            % Enable Hit and Stand buttons
            app.HitButton.Enable = 'on';
            app.StandButton.Enable = 'on';

        end

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)
            % Hit Button callback
            % function HitButtonPushed(app, event)
            [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard(app.playerHand);

            % Check if the player busts
            if app.BlackjackGame.calculateTotal(app.playerHand) > 21
                app.updateDisplay();
                app.endGame('Bust! You lose.');
                return;
            end

            % Update the display if player hasn't busted
            app.updateDisplay();

        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            % % Stand Button callback
            % function StandButtonPushed(app, event)
            % Dealer's turn
            app.BlackjackGame = app.BlackjackGame.dealerTurn(app.playerHand, app.dealerHand, app.currentBet);

            % Update display and show result
            app.updateDisplay();
            app.endGame('Game over. Check balance and results.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlackjackManMachinePanel = uipanel(app.UIFigure);
            app.BlackjackManMachinePanel.Title = 'Blackjack: Man & Machine ';
            app.StandButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.HitMeButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Text = 'Hit Me';
            app.StartResetButton = uibutton(app.BlackjackManMachinePanel, 'push');
            app.StartResetButton.ButtonPushedFcn = createCallbackFcn(app, @StartResetButtonPushed, true);
            app.StartResetButton.Text = 'Start/Reset';
            app.BalanceEditFieldLabel = uilabel(app.BlackjackManMachinePanel);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.BlackjackManMachinePanel, 'numeric');
            app.EStopToggleButton = uibutton(app.BlackjackManMachinePanel, 'state');
            app.EStopToggleButton.Text = 'E-Stop (Toggle)';
            app.CurrentBetEditFieldLabel = uilabel(app.BlackjackManMachinePanel);
            app.CurrentBetEditFieldLabel.Text = 'Current Bet';
            app.CurrentBetEditField = uieditfield(app.BlackjackManMachinePanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end